classdef ImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageDirEditFieldLabel    matlab.ui.control.Label
        ImageDirEditField         matlab.ui.control.EditField
        ImageIDEditFieldLabel     matlab.ui.control.Label
        ImageIDEditField          matlab.ui.control.NumericEditField
        ReadImageButton           matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        PrevButton                matlab.ui.control.Button
        AddVertexButton           matlab.ui.control.Button
        InitializeButton          matlab.ui.control.Button
        ImageTypeDropDown         matlab.ui.control.DropDown
        vtxDoneButton             matlab.ui.control.Button
        AddFaceButton             matlab.ui.control.Button
        faceDoneButton            matlab.ui.control.Button
        SaveDirEditFieldLabel     matlab.ui.control.Label
        SaveDirEditField          matlab.ui.control.EditField
        saveButton                matlab.ui.control.Button
        UIAxes3D                  matlab.ui.control.UIAxes
        RoofHeightEditFieldLabel  matlab.ui.control.Label
        RoofHeightEditField       matlab.ui.control.NumericEditField
        BodyHeightEditFieldLabel  matlab.ui.control.Label
        BodyHeightEditField       matlab.ui.control.NumericEditField
        RoofOptimizationButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        ImageViewer
        Image
        ImageName
        num_files
        body_height
        roof_height
        RG
        M
        verts
        faces
        nv
        nf
        ne
    end
    
    methods (Access = private)
        
        function [] = initialize_roof_graph(app)
            app.verts = [];
            app.faces = {};
        end
        
        function [] = info_roof_graph(app)
            app.nv = size(app.verts, 1);
            app.ne = size(app.edges,1);
            app.nf = length(app.faces);
        end
        
        function [] = update_3D_axes(app)
            cla(app.UIAxes3D);
            hold(app.UIAxes3D, 'on')
            %             update_building_from_valid_2D_embedding(app);
            
            X = app.M.verts;
            X = X*[1,0,0;0,-1,0;0,0,1];
            F = app.M.faces;
            labels = app.M.face_labels;
            colors = lines(10);
            for i = 1:length(F)
                face = F{i};
                Y = X(face,:);
                fill3(Y(:,1), Y(:,2), Y(:,3),colors(labels(i),:),'LineWidth',2,...
                    'Parent',app.UIAxes3D);
            end
            axis equal;
            view(30,60);
            hold(app.UIAxes3D, 'off')
        end
        
        function [] = plot_roof_graph(app)
            cla(app.ImageViewer);
            imshow(app.Image, 'Parent' ,app.ImageViewer)
            hold(app.ImageViewer, 'on' )
            if ~isempty(app.verts)
                scatter(app.verts(:,1), app.verts(:,2),50,'red','filled','Parent',app.ImageViewer)
            end
            
            face_colors = lines(100);
            if ~isempty(app.faces)
                V = app.verts;
                F = app.faces;
                for i = 1:length(F)
                    face = V(F{i},:);
                    fill(face(:,1), face(:,2),face_colors(i,:),'FaceAlpha','flat','FaceVertexAlphaData',0.2, 'Parent',app.ImageViewer)
                end
            end
            hold(app.ImageViewer, 'off' )
        end
        function [] = reset_3D_axes(app)
            
            cla(app.UIAxes3D,'reset');
            title(app.UIAxes3D, 'Constructed Roof')
            app.UIAxes3D.DataAspectRatio = [1 1 1];
            app.UIAxes3D.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes3D.Box = 'on';
            app.UIAxes3D.XColor = [0 0 0];
            app.UIAxes3D.XTick = [];
            app.UIAxes3D.XTickLabel = {};
            app.UIAxes3D.YTick = [];
            app.UIAxes3D.YTickLabel = {};
            app.UIAxes3D.ZTick = [];
            app.UIAxes3D.Color = 'none';
            app.UIAxes3D.View = [30 60];
            app.UIAxes3D.Position = [682 98 300 281];
        end
    end


    methods (Access = private)

        % Button pushed function: ReadImageButton
        function ReadImageButtonPushed(app, event)
            addpath(genpath('utils/'))
            InitializeButtonPushed(app, event)
            file_dir = app.ImageDirEditField.Value;
            file_id = app.ImageIDEditField.Value;
            all_files = dir([file_dir, '*',app.ImageTypeDropDown.Value]);
            
            if file_id <= length(all_files)
                file_name = all_files(file_id).name;
                I = imread([file_dir, file_name]);
                app.Image = I;
                app.ImageName = file_name(1:end-4);
                imshow(I,'Parent',app.ImageViewer)
                %title(app.ImageViewer, file_name(1:end-4),'Interpreter','none');
            else
                error('ImageID exceeds')
            end
            initialize_roof_graph(app);
            reset_3D_axes(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            all_files = dir([app.ImageDirEditField.Value, '*', app.ImageTypeDropDown.Value]);
            num = length(all_files);
            app.ImageIDEditField.Value = min(app.ImageIDEditField.Value + 1, num);
            ReadImageButtonPushed(app, event)
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.ImageIDEditField.Value = max(app.ImageIDEditField.Value - 1, 1);
            ReadImageButtonPushed(app, event)
        end

        % Button pushed function: AddVertexButton
        function AddVertexButtonPushed(app, event)
            set(app.vtxDoneButton,'userdata',0);
            for iter = 1:100
                pc = drawpoint('Parent', app.ImageViewer, 'Visible', 'off');    %Use Mouse To Select a point ROI
                if isvalid(pc) && ~get(app.vtxDoneButton, 'userdata')
                    app.verts = [app.verts; pc.Position];
                    plot_roof_graph(app);
                end
                
                if get(app.vtxDoneButton, 'userdata')
                    break
                end
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            arrayfun(@cla,findall(0,'type','axes'))
            arrayfun(@cla,findall(0,'type','text'))
            % Create ImageViewer
            app.ImageViewer = axes(app.UIFigure, 'Units', 'Pixels', ...
                'Position',[25 35 480 321], 'XTick', [], 'YTick', []);
            %              cla(app.ImageViewer, 'reset');
        end

        % Button pushed function: vtxDoneButton
        function vtxDoneButtonPushed(app, event)
            set(app.vtxDoneButton,'userdata',1)
        end

        % Button pushed function: AddFaceButton
        function AddFaceButtonPushed(app, event)
            set(app.faceDoneButton,'userdata',0);
            for iter = 1:100
                face = drawpolygon('Parent', app.ImageViewer);
                if ~get(app.faceDoneButton, 'userdata') && ~isempty(face.Position)
                    face_vid = reshape(knnsearch(app.verts, face.Position),1,[]);
                    if length(face_vid) < 3
                        warning('Invalid input edge');
                    else
                        app.faces{end+1} = face_vid;
                        plot_roof_graph(app)
                    end
                    
                    plot_roof_graph(app);
                end
                
                if get(app.faceDoneButton, 'userdata')
                    break
                end
            end
        end

        % Button pushed function: faceDoneButton
        function faceDoneButtonPushed(app, event)
            set(app.faceDoneButton,'userdata',1)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            save_dir = app.SaveDirEditField.Value;
            dlmwrite([save_dir, app.ImageName,'.verts'], app.verts);
            for i = 1:length(app.faces)
                dlmwrite([save_dir, app.ImageName, '.faces'], app.faces{i},'-append');
            end
        end

        % Button pushed function: RoofOptimizationButton
        function RoofOptimizationButtonPushed(app, event)
            % update the roof_height/body_height
            app.roof_height = app.RoofHeightEditField.Value;
            app.body_height = app.BodyHeightEditField.Value;
            
            V = app.verts;
            F = app.faces;
            obj = RoofGraph(V, F);
            obj = reindex_roofgraph(obj);
            [obj,R] = axis_align_roof_graph(obj);
            obj = regularize_outline_edges(obj);
            obj.V = obj.V*R';
            
            
            para.eps_ortho = 1e-3;
            para.lambda_minEdgeLen = 0;
            para.minEdgeLen = 0.5;
            para.lambda_ini = 1e-6;
            obj = optimize_2D_feasible_roofgraph_close2ini(obj, para);
            
            X1 = construct_3D_roof_from_straight_skeleton(obj.V, obj.F, obj.vid_roof, app.roof_height);
            app.M = construct_building(X1, obj.F, app.body_height);
            app.RG = obj;
            update_3D_axes(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageDirEditFieldLabel = uilabel(app.UIFigure);
            app.ImageDirEditFieldLabel.Text = 'Image Dir';
            app.ImageDirEditField = uieditfield(app.UIFigure, 'text');
            app.ImageDirEditField.Value = '/Users/renj/Renj/Project/PROJ2020_roof_construction/data/image/';
            app.ImageIDEditFieldLabel = uilabel(app.UIFigure);
            app.ImageIDEditFieldLabel.Text = 'Image ID';
            app.ImageIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageIDEditField.Value = 1;
            app.ReadImageButton = uibutton(app.UIFigure, 'push');
            app.ReadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadImageButtonPushed, true);
            app.ReadImageButton.Text = 'Read Image';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.AddVertexButton = uibutton(app.UIFigure, 'push');
            app.AddVertexButton.ButtonPushedFcn = createCallbackFcn(app, @AddVertexButtonPushed, true);
            app.AddVertexButton.Text = 'Add Vertex';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Visible = 'off';
            app.InitializeButton.Text = 'Initialize';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'.jpg', '.png', '.jpeg'};
            app.ImageTypeDropDown.Value = '.jpg';
            app.vtxDoneButton = uibutton(app.UIFigure, 'push');
            app.vtxDoneButton.ButtonPushedFcn = createCallbackFcn(app, @vtxDoneButtonPushed, true);
            app.vtxDoneButton.Text = 'done';
            app.AddFaceButton = uibutton(app.UIFigure, 'push');
            app.AddFaceButton.ButtonPushedFcn = createCallbackFcn(app, @AddFaceButtonPushed, true);
            app.AddFaceButton.Text = 'Add Face';
            app.faceDoneButton = uibutton(app.UIFigure, 'push');
            app.faceDoneButton.ButtonPushedFcn = createCallbackFcn(app, @faceDoneButtonPushed, true);
            app.faceDoneButton.Text = 'done';
            app.SaveDirEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirEditField.Value = '/Users/renj/Renj/Project/PROJ2020_roof_construction/data/label_jing/';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, 'Constructed Roof')
            app.UIAxes3D.XTick = [];
            app.UIAxes3D.XTickLabel = {};
            app.UIAxes3D.YTick = [];
            app.UIAxes3D.YTickLabel = {};
            app.UIAxes3D.ZTick = [];
            app.RoofHeightEditFieldLabel = uilabel(app.UIFigure);
            app.RoofHeightEditFieldLabel.Text = 'Roof Height';
            app.RoofHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoofHeightEditField.Value = 50;
            app.BodyHeightEditFieldLabel = uilabel(app.UIFigure);
            app.BodyHeightEditFieldLabel.Text = 'Body Height';
            app.BodyHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.BodyHeightEditField.Value = 100;
            app.RoofOptimizationButton = uibutton(app.UIFigure, 'push');
            app.RoofOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RoofOptimizationButtonPushed, true);
            app.RoofOptimizationButton.Text = 'Roof Optimization';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageReconstruction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end