classdef RoofEditting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RoofDirEditFieldLabel     matlab.ui.control.Label
        RoofDirEditField          matlab.ui.control.EditField
        RoofIDEditFieldLabel      matlab.ui.control.Label
        RoofIDEditField           matlab.ui.control.NumericEditField
        ReadRoofButton            matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        PrevButton                matlab.ui.control.Button
        InitializeButton          matlab.ui.control.Button
        ImageTypeDropDown         matlab.ui.control.DropDown
        SaveDirEditFieldLabel     matlab.ui.control.Label
        SaveDirEditField          matlab.ui.control.EditField
        saveButton                matlab.ui.control.Button
        UIAxesImage               matlab.ui.control.UIAxes
        UIAxes2D                  matlab.ui.control.UIAxes
        UIAxes3D                  matlab.ui.control.UIAxes
        SnapEdgeButton            matlab.ui.control.Button
        EdgeIDEditFieldLabel      matlab.ui.control.Label
        SnapEdgeIDEditField       matlab.ui.control.NumericEditField
        RoofHeightEditFieldLabel  matlab.ui.control.Label
        RoofHeightEditField       matlab.ui.control.NumericEditField
        ResetViewButton           matlab.ui.control.Button
        OptimizePlanarityButton   matlab.ui.control.Button
        MergeTwoFacesButton       matlab.ui.control.Button
        FaceID1Label              matlab.ui.control.Label
        MFfid1EditField           matlab.ui.control.NumericEditField
        FaceID2Label              matlab.ui.control.Label
        MFfid2EditField           matlab.ui.control.NumericEditField
        MoveVertexButton          matlab.ui.control.Button
        VtxIDEditFieldLabel       matlab.ui.control.Label
        MVVtxIDEditField          matlab.ui.control.NumericEditField
        moveXEditFieldLabel       matlab.ui.control.Label
        moveXEditField            matlab.ui.control.NumericEditField
        moveYEditFieldLabel       matlab.ui.control.Label
        moveYEditField            matlab.ui.control.NumericEditField
        ForceAdjacencyButton      matlab.ui.control.Button
        FaceID1Label_2            matlab.ui.control.Label
        FAfid1EditField           matlab.ui.control.NumericEditField
        FaceID1Label_3            matlab.ui.control.Label
        FAfid2EditField           matlab.ui.control.NumericEditField
        SplitFaceatVtxButton      matlab.ui.control.Button
        FaceIDLabel               matlab.ui.control.Label
        splitfidEditField         matlab.ui.control.NumericEditField
        VtxID1Label               matlab.ui.control.Label
        splitVtxID1EditField      matlab.ui.control.NumericEditField
        VtxID2Label               matlab.ui.control.Label
        splitVtxID2EditField      matlab.ui.control.NumericEditField
        MoveEdgeButton            matlab.ui.control.Button
        EdgeDLabel                matlab.ui.control.Label
        MEedgeIDEditField         matlab.ui.control.NumericEditField
        moveXEditField_2Label     matlab.ui.control.Label
        MEmoveXEditField          matlab.ui.control.NumericEditField
        moveYEditField_2Label     matlab.ui.control.Label
        MEmoveYEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        ImageViewer
        Image
        ImageName
        num_files
        roof_height
        body_height
        RG
        M
        verts
        edges
        faces
        nv
        nf
        ne
    end
    
    methods (Access = private)
        
        function [] = update_image_axes(app)
            imshow(app.Image,'Parent', app.UIAxesImage);
            app.UIAxesImage.Title.String = app.ImageName;
            app.UIAxesImage.Title.Interpreter = 'None';
        end
        
        function [] = update_2D_axes(app)
            cla(app.UIAxes2D)
            f1 = imshow(app.Image,'Parent', app.UIAxes2D);
            hold(app.UIAxes2D, 'on' )
            obj = app.RG;
%             obj.V = obj.V*[1,0;0,-1];
            %             color = lines(obj.nf);
            rng(2);
            color = rand(100,3);
            scatter(obj.V(:,1), obj.V(:,2),20,'k','filled',...
                'Parent',app.UIAxes2D);
            
            for eid = reshape(obj.eid_outline,1,[])
                edge = obj.V(obj.E(eid,:),:);
                plot(edge(:,1), edge(:,2), 'g-.','Color', 'g','LineWidth',2,...
                    'Parent',app.UIAxes2D);
            end
            
            for eid = reshape(obj.eid_ridge,1,[])
                edge = obj.V(obj.E(eid,:),:);
                plot(edge(:,1), edge(:,2), 'r-','Color', 'r', 'LineWidth',2,...
                    'Parent',app.UIAxes2D);
            end
            
            for eid = reshape(obj.eid_roof,1,[])
                edge = obj.V(obj.E(eid,:),:);
                plot(edge(:,1), edge(:,2), 'k--', 'LineWidth',2,...
                    'Parent',app.UIAxes2D);
            end
            
            for fid = 1:obj.nf
                face = obj.V(obj.F{fid},:);
                fill(face(:,1), face(:,2), color(fid,:),'FaceAlpha',0.1,'EdgeAlpha',0,...
                    'Parent', app.UIAxes2D);
            end
            
            
            % add edge labels
            for eid = 1:size(obj.E,1)
                edge = obj.V(obj.E(eid,:),:);
                x = mean(edge);
                text(x(1), x(2),['e',num2str(eid)],'Color','blue',...
                    'Parent',app.UIAxes2D);
            end
            
            % add face labels
            for fid = 1:size(obj.F,1)
                face =  obj.V(obj.F{fid},:);
                x = mean(face);
                text(x(1), x(2),['f',num2str(fid)],'Color','red',...
                    'Parent',app.UIAxes2D);
            end
            
            % add vertex labels
            for vid = 1:size(obj.V,1)
                x = obj.V(vid,:);
                text(x(1), x(2),['v',num2str(vid)],'Color','red',...
                    'Parent',app.UIAxes2D);
            end
            
            axis equal;
            hold(app.UIAxes2D, 'off' )
            
            set(f1,'AlphaData', 0.5);

        end
        
        function [] = update_3D_axes(app)
            cla(app.UIAxes3D);
            hold(app.UIAxes3D, 'on')
            update_building_from_valid_2D_embedding(app);
            
            X = app.M.verts;
            X = X*[1,0,0;0,-1,0;0,0,1];
            F = app.M.faces;
            labels = app.M.face_labels;
            colors = lines(10);
            for i = 1:length(F)
                face = F{i};
                Y = X(face,:);
                fill3(Y(:,1), Y(:,2), Y(:,3),colors(labels(i),:),'LineWidth',2,...
                    'Parent',app.UIAxes3D);
            end
            axis equal;
            view(30,60);
            hold(app.UIAxes3D, 'off')
        end
        
        function app = update_building_from_valid_2D_embedding(app)
            % construct the building
            obj = app.RG;
            X1 = construct_3D_roof_from_straight_skeleton(obj.V, obj.F, obj.vid_roof, app.roof_height);
            app.M = construct_building(X1, obj.F, app.body_height);
        end
    end

    methods (Access = private)

        % Button pushed function: ReadRoofButton
        function ReadRoofButtonPushed(app, event)
            addpath(genpath('utils/'))
            file_dir = app.RoofDirEditField.Value;
            file_id = app.RoofIDEditField.Value;
            all_files = dir([file_dir, '*',app.ImageTypeDropDown.Value]);
            
            % the height for the roof and building body
            app.roof_height = app.RoofHeightEditField.Value;
            app.body_height = 2*app.roof_height;
            
            if file_id <= length(all_files)
                file_name = all_files(file_id).name;
                tmp = strsplit(file_name,'.');
                app.ImageName = tmp{1};
                
                I = imread([file_dir, app.ImageName, '.jpg']);
                I1 = flipdim(I ,1);           %# vertical flip
                app.Image = I;
                
                % read the constructed roofs from the straight skeleton
                [V_cgal, F_cgal] = read_straight_skeleton([file_dir, app.ImageName, '.faces']);
                obj = RoofGraph(V_cgal, F_cgal);
                obj = reindex_roofgraph(obj);
                [obj,R] = axis_align_roof_graph(obj);
                obj = regularize_outline_edges(obj);
                obj.V = obj.V*R';
                app.RG = obj;
                
                app = update_building_from_valid_2D_embedding(app);
                
                update_image_axes(app);
                ResetViewButtonPushed(app)
%                 update_2D_axes(app);
%                 update_3D_axes(app);
            else
                error('ImageID exceeds')
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            save_dir = app.SaveDirEditField.Value;
            dlmwrite([save_dir, app.ImageName,'.verts'], app.verts);
            for i = 1:length(app.faces)
                dlmwrite([save_dir, app.ImageName, '.faces'], app.faces{i},'-append');
            end
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            cla(app.UIAxes2D,'reset')
            title(app.UIAxes2D, '2D Embedding')
            app.UIAxes2D.DataAspectRatio = [1 1 1];
            app.UIAxes2D.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes2D.Box = 'on';
            app.UIAxes2D.XTick = [];
            app.UIAxes2D.YTick = [];
            app.UIAxes2D.Color = 'none';
            app.UIAxes2D.Position = [332 175 314 321];
            
            % Create UIAxes3D
            cla(app.UIAxes3D,'reset')
            title(app.UIAxes3D, '3D Embedding')
            app.UIAxes3D.DataAspectRatio = [1 1 1];
            app.UIAxes3D.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes3D.Box = 'on';
            app.UIAxes3D.XTick = [];
            app.UIAxes3D.YTick = [];
            app.UIAxes3D.ZTick = [];
            app.UIAxes3D.Color = 'none';
            app.UIAxes3D.View = [30 60];
            app.UIAxes3D.Position = [660 175 314 321];
            
            update_2D_axes(app);
            update_3D_axes(app);
        end

        % Button pushed function: SnapEdgeButton
        function SnapEdgeButtonPushed(app, event)
            obj = app.RG;
            rm_eid = app.SnapEdgeIDEditField.Value;
            app.RG = remove_ridge_edge(obj, rm_eid);
            update_2D_axes(app);
        end

        % Button pushed function: OptimizePlanarityButton
        function OptimizePlanarityButtonPushed(app, event)
            obj = app.RG;
            app.RG = optimize_2D_feasible_roofgraph_close2ini(obj);
            app = update_building_from_valid_2D_embedding(app);
            update_2D_axes(app);
            update_3D_axes(app);
        end

        % Button pushed function: MergeTwoFacesButton
        function MergeTwoFacesButtonPushed(app, event)
            ref_fid1 = app.MFfid1EditField.Value;
            ref_fid2 = app.MFfid2EditField.Value;
            app.RG = merge_two_faces(app.RG, ref_fid1, ref_fid2);
            update_2D_axes(app);
        end

        % Button pushed function: MoveVertexButton
        function MoveVertexButtonPushed(app, event)
            vid = app.MVVtxIDEditField.Value;
            x = app.moveXEditField.Value;
            y = app.moveYEditField.Value;
            mov_pos = [x,-y];
            app.RG.V(vid,:) = app.RG.V(vid,:) + mov_pos;
            update_2D_axes(app);
            
        end

        % Button pushed function: ForceAdjacencyButton
        function ForceAdjacencyButtonPushed(app, event)
            ref_fid1 = app.FAfid1EditField.Value;
            ref_fid2 = app.FAfid2EditField.Value;
            app.RG = force_two_face_adjacent(app.RG, ref_fid1, ref_fid2);
            update_2D_axes(app);
        end

        % Button pushed function: SplitFaceatVtxButton
        function SplitFaceatVtxButtonPushed(app, event)
            fid = app.splitfidEditField.Value;
            vid1 = app.splitVtxID1EditField.Value;
            vid2 = app.splitVtxID2EditField.Value;
            app.RG = split_face_at_two_vtx(app.RG, fid, vid1, vid2);
            update_2D_axes(app);
        end

        % Button pushed function: MoveEdgeButton
        function MoveEdgeButtonPushed(app, event)
            eid = app.MEedgeIDEditField.Value;
            x = app.MEmoveXEditField.Value;
            y = app.MEmoveYEditField.Value;
            vid = app.RG.E(eid,:);
            app.RG.V(vid,:) = app.RG.V(vid,:) + [x,-y];
            update_2D_axes(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RoofDirEditFieldLabel = uilabel(app.UIFigure);
            app.RoofDirEditFieldLabel.Text = 'Roof Dir';
            app.RoofDirEditField = uieditfield(app.UIFigure, 'text');
            app.RoofDirEditField.Value = '/Users/renj/Renj/Project/PROJ2021_roof_GUI/data_editting/';
            app.RoofIDEditFieldLabel = uilabel(app.UIFigure);
            app.RoofIDEditFieldLabel.Text = 'Roof ID';
            app.RoofIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoofIDEditField.Value = 1;
            app.ReadRoofButton = uibutton(app.UIFigure, 'push');
            app.ReadRoofButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRoofButtonPushed, true);
            app.ReadRoofButton.Text = 'Read Roof';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.Text = 'Prev';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.Visible = 'off';
            app.InitializeButton.Text = 'Initialize';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'.faces'};
            app.ImageTypeDropDown.Value = '.faces';
            app.SaveDirEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirEditField.Value = '/Users/renj/Renj/Project/PROJ2021_roof_GUI/data/labels/';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.UIAxesImage = uiaxes(app.UIFigure);
            title(app.UIAxesImage, 'Input Image')
            app.UIAxesImage.XTick = [];
            app.UIAxesImage.YTick = [];
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, '2D Embedding')
            app.UIAxes2D.XTick = [];
            app.UIAxes2D.YTick = [];
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, '3D Embedding')
            app.UIAxes3D.XTick = [];
            app.UIAxes3D.YTick = [];
            app.UIAxes3D.ZTick = [];
            app.SnapEdgeButton = uibutton(app.UIFigure, 'push');
            app.SnapEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @SnapEdgeButtonPushed, true);
            app.SnapEdgeButton.Text = 'Snap Edge';
            app.EdgeIDEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeIDEditFieldLabel.Text = 'EdgeID';
            app.SnapEdgeIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoofHeightEditFieldLabel = uilabel(app.UIFigure);
            app.RoofHeightEditFieldLabel.Text = 'Roof Height';
            app.RoofHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoofHeightEditField.Value = 50;
            app.ResetViewButton = uibutton(app.UIFigure, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.OptimizePlanarityButton = uibutton(app.UIFigure, 'push');
            app.OptimizePlanarityButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePlanarityButtonPushed, true);
            app.OptimizePlanarityButton.Text = 'Optimize Planarity';
            app.MergeTwoFacesButton = uibutton(app.UIFigure, 'push');
            app.MergeTwoFacesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeTwoFacesButtonPushed, true);
            app.MergeTwoFacesButton.Text = 'Merge Two Faces';
            app.FaceID1Label = uilabel(app.UIFigure);
            app.FaceID1Label.Text = 'FaceID1';
            app.MFfid1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FaceID2Label = uilabel(app.UIFigure);
            app.FaceID2Label.Text = 'FaceID2';
            app.MFfid2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveVertexButton = uibutton(app.UIFigure, 'push');
            app.MoveVertexButton.ButtonPushedFcn = createCallbackFcn(app, @MoveVertexButtonPushed, true);
            app.MoveVertexButton.Text = 'Move Vertex';
            app.VtxIDEditFieldLabel = uilabel(app.UIFigure);
            app.VtxIDEditFieldLabel.Text = 'VtxID';
            app.MVVtxIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.moveXEditFieldLabel = uilabel(app.UIFigure);
            app.moveXEditFieldLabel.Text = 'moveX';
            app.moveXEditField = uieditfield(app.UIFigure, 'numeric');
            app.moveYEditFieldLabel = uilabel(app.UIFigure);
            app.moveYEditFieldLabel.Text = 'moveY';
            app.moveYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceAdjacencyButton = uibutton(app.UIFigure, 'push');
            app.ForceAdjacencyButton.ButtonPushedFcn = createCallbackFcn(app, @ForceAdjacencyButtonPushed, true);
            app.ForceAdjacencyButton.Text = 'Force Adjacency';
            app.FaceID1Label_2 = uilabel(app.UIFigure);
            app.FaceID1Label_2.Text = 'FaceID1';
            app.FAfid1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FaceID1Label_3 = uilabel(app.UIFigure);
            app.FaceID1Label_3.Text = 'FaceID1';
            app.FAfid2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SplitFaceatVtxButton = uibutton(app.UIFigure, 'push');
            app.SplitFaceatVtxButton.ButtonPushedFcn = createCallbackFcn(app, @SplitFaceatVtxButtonPushed, true);
            app.SplitFaceatVtxButton.Text = 'Split Face at Vtx';
            app.FaceIDLabel = uilabel(app.UIFigure);
            app.FaceIDLabel.Text = 'FaceID';
            app.splitfidEditField = uieditfield(app.UIFigure, 'numeric');
            app.VtxID1Label = uilabel(app.UIFigure);
            app.VtxID1Label.Text = 'VtxID1';
            app.splitVtxID1EditField = uieditfield(app.UIFigure, 'numeric');
            app.VtxID2Label = uilabel(app.UIFigure);
            app.VtxID2Label.Text = 'VtxID2';
            app.splitVtxID2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveEdgeButton = uibutton(app.UIFigure, 'push');
            app.MoveEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @MoveEdgeButtonPushed, true);
            app.MoveEdgeButton.Text = 'Move Edge';
            app.EdgeDLabel = uilabel(app.UIFigure);
            app.EdgeDLabel.Text = 'EdgeD';
            app.MEedgeIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.moveXEditField_2Label = uilabel(app.UIFigure);
            app.moveXEditField_2Label.Text = 'moveX';
            app.MEmoveXEditField = uieditfield(app.UIFigure, 'numeric');
            app.moveYEditField_2Label = uilabel(app.UIFigure);
            app.moveYEditField_2Label.Text = 'moveY';
            app.MEmoveYEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = RoofEditting

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end